classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModificardatosButton            matlab.ui.control.Button
        CalculadoradeprobabilidadPanel  matlab.ui.container.Panel
        ProbabilidaddentrarLabel        matlab.ui.control.Label
        ResulProba                      matlab.ui.control.Label
        calcularButton                  matlab.ui.control.Button
        PuntosInput                     matlab.ui.control.NumericEditField
        PuntosGREEditFieldLabel         matlab.ui.control.Label
        EcuacindelarectaPanel           matlab.ui.container.Panel
        labelModelo                     matlab.ui.control.Label
        miTabla2                        matlab.ui.control.Table
        miGrafica                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pendiente, ordenada, x, y; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Obtenemos los datos
            datos = readtable('Admission_Predict.csv'); % Leer el excel

            % Tomamos GREScore y ChanceOfAdmit
            columnasUsadas = {'GREScore', 'ChanceOfAdmit'};
            datos = datos(1:400, columnasUsadas);
            app.miTabla2.Data = datos; % Ya tiene formato de tabla

            % Para graficar
            app.x = table2array( datos(1:400,'GREScore') );
            app.y = table2array( datos(1:400,'ChanceOfAdmit') );

            plot(app.miGrafica,app.x, app.y,'.'); % * . X p h o + s d
            hold(app.miGrafica,'on');
             % R E G R E S I Ó N 
            coeficientes = polyfit(app.x, app.y, 1);

            % Obtener los coeficientes de la recta
            app.pendiente = coeficientes(1);
            app.ordenada = coeficientes(2);

             plot(app.miGrafica, app.x, app.pendiente*app.x + app.ordenada);

             % M O D E L O
             app.labelModelo.Text = sprintf("y = %fx %f", app.pendiente,app.ordenada);
        end

        % Button pushed function: calcularButton
        function calcularButtonPushed(app, event)
           proba = app.pendiente.*app.PuntosInput.Value + app.ordenada;
           app.ResulProba.Text = sprintf("%f",proba);
        end

        % Button pushed function: ModificardatosButton
        function ModificardatosButtonPushed(app, event)
            % Botón para poder re-greficar los datos
            modificacionWindow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            caratula
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.miGrafica = uiaxes(app.UIFigure);
            title(app.miGrafica, 'Regresión lineal')
            xlabel(app.miGrafica, 'GRE Score (X)')
            ylabel(app.miGrafica, 'Chance to Admit (Y)')
            zlabel(app.miGrafica, 'Z')
            colormap(app.miGrafica, 'turbo')
            app.miTabla2 = uitable(app.UIFigure);
            app.miTabla2.ColumnName = {'GREScore'; 'ChanceAdmit'};
            app.miTabla2.RowName = {};
            app.EcuacindelarectaPanel = uipanel(app.UIFigure);
            app.EcuacindelarectaPanel.Title = 'Ecuación de la recta';
            app.labelModelo = uilabel(app.EcuacindelarectaPanel);
            app.labelModelo.Text = 'y = mx + b';
            app.CalculadoradeprobabilidadPanel = uipanel(app.UIFigure);
            app.CalculadoradeprobabilidadPanel.Title = 'Calculadora de probabilidad';
            app.PuntosGREEditFieldLabel = uilabel(app.CalculadoradeprobabilidadPanel);
            app.PuntosGREEditFieldLabel.Text = 'Puntos GRE';
            app.PuntosInput = uieditfield(app.CalculadoradeprobabilidadPanel, 'numeric');
            app.calcularButton = uibutton(app.CalculadoradeprobabilidadPanel, 'push');
            app.calcularButton.ButtonPushedFcn = createCallbackFcn(app, @calcularButtonPushed, true);
            app.calcularButton.Text = 'calcular';
            app.ResulProba = uilabel(app.CalculadoradeprobabilidadPanel);
            app.ResulProba.Text = '';
            app.ProbabilidaddentrarLabel = uilabel(app.CalculadoradeprobabilidadPanel);
            app.ProbabilidaddentrarLabel.Text = 'Probabilidad de ntrar';
            app.ModificardatosButton = uibutton(app.UIFigure, 'push');
            app.ModificardatosButton.ButtonPushedFcn = createCallbackFcn(app, @ModificardatosButtonPushed, true);
            app.ModificardatosButton.Text = 'Modificar datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end