classdef modificacionWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NuevapredicinPanel           matlab.ui.container.Panel
        NuevaprediccionLabel         matlab.ui.control.Label
        ObtenernuevaprediccinButton  matlab.ui.control.Button
        puntosInput2                 matlab.ui.control.NumericEditField
        GREScoreEditFieldLabel       matlab.ui.control.Label
        miTabla2                     matlab.ui.control.Table
        InstruccionesdeusoPanel      matlab.ui.container.Panel
        ModificacindedatosLabel      matlab.ui.control.Label
        GraficacindedatosLabel       matlab.ui.control.Label
        ModificacindedartosLabel     matlab.ui.control.Label
        GraficarButton               matlab.ui.control.Button
        miGrafica2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        x,y,pendiente, ordenada; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Obtenemos los datos
            datos2 = readtable('Admission_Predict.csv');

            % Tomamos GREScore y ChanceOfAdmit
            columnasUsadas = {'GREScore', 'ChanceOfAdmit'};
            datos2 = datos2(1:400, columnasUsadas);
            app.miTabla2.Data = datos2; % Ya tiene formato de tabla

            app.x = table2array( datos2(1:400,'GREScore') );
            app.y = table2array( datos2(1:400,'ChanceOfAdmit') ); 

            % R E G R E S I Ó N 
            coeficientes = polyfit(app.x, app.y, 1);

            % Obtener los coeficientes de la recta
            app.pendiente = coeficientes(1);
            app.ordenada = coeficientes(2);

        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            % Obtenemos los datos de la tabla nuevamente

            datos2 = app.miTabla2.Data; % Ya tiene formato de tabla

            app.x = table2array( datos2(1:400,'GREScore') );
            app.y = table2array( datos2(1:400,'ChanceOfAdmit') ); 

            % Borramos la grafica anterior
            cla(app.miGrafica2)


           % R E G R E S I Ó N 
            coeficientes = polyfit(app.x, app.y, 1);
            % Obtener los coeficientes de la recta
            app.pendiente = coeficientes(1);
            app.ordenada = coeficientes(2);


            % Graficamos
            plot(app.miGrafica2, app.x, app.y,'.'); % * . X p h o + s d
            hold(app.miGrafica2,'on');
            plot(app.miGrafica2, app.x, app.pendiente*app.x + app.ordenada);

        end

        % Button pushed function: ObtenernuevaprediccinButton
        function ObtenernuevaprediccinButtonPushed(app, event)
            % Para obtener la nueva predicción
            proba = app.pendiente.*app.puntosInput2.Value + app.ordenada;
            app.NuevaprediccionLabel.Text = sprintf("%f",proba);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.miGrafica2 = uiaxes(app.UIFigure);
            title(app.miGrafica2, 'Regresión lineal')
            xlabel(app.miGrafica2, 'X')
            ylabel(app.miGrafica2, 'Y')
            zlabel(app.miGrafica2, 'Z')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.InstruccionesdeusoPanel = uipanel(app.UIFigure);
            app.InstruccionesdeusoPanel.Title = 'Instrucciones de uso';
            app.ModificacindedartosLabel = uilabel(app.InstruccionesdeusoPanel);
            app.ModificacindedartosLabel.Text = {'Para modificar los datos solamente haz click en el dato de la tabla de la derecha'; 'que desees cambiar.'; ''; ''; 'Una vez que hayas editado los datos solamente haz click en el botón "Grafcar que'; 'se encuentra debajo de la tabla de datos.'};
            app.GraficacindedatosLabel = uilabel(app.InstruccionesdeusoPanel);
            app.GraficacindedatosLabel.Text = 'Graficación de datos:';
            app.ModificacindedatosLabel = uilabel(app.InstruccionesdeusoPanel);
            app.ModificacindedatosLabel.Text = 'Modificación de datos:';
            app.miTabla2 = uitable(app.UIFigure);
            app.miTabla2.ColumnName = {'GREScore'; 'ChanceAdmit'};
            app.miTabla2.RowName = {};
            app.miTabla2.ColumnEditable = true;
            app.NuevapredicinPanel = uipanel(app.UIFigure);
            app.NuevapredicinPanel.Title = 'Nueva predición';
            app.GREScoreEditFieldLabel = uilabel(app.NuevapredicinPanel);
            app.GREScoreEditFieldLabel.Text = 'GRE Score';
            app.puntosInput2 = uieditfield(app.NuevapredicinPanel, 'numeric');
            app.ObtenernuevaprediccinButton = uibutton(app.NuevapredicinPanel, 'push');
            app.ObtenernuevaprediccinButton.ButtonPushedFcn = createCallbackFcn(app, @ObtenernuevaprediccinButtonPushed, true);
            app.ObtenernuevaprediccinButton.Text = 'Obtener nueva predicción';
            app.NuevaprediccionLabel = uilabel(app.NuevapredicinPanel);
            app.NuevaprediccionLabel.Text = 'Nueva presicción';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modificacionWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end